classdef RBC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                         matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        ViewButton                 matlab.ui.control.Button
        MeanLabel                  matlab.ui.control.Label
        VarianceLabel              matlab.ui.control.Label
        CoVarianceLabel            matlab.ui.control.Label
        StandardDeviationLabel     matlab.ui.control.Label
        EditField_1                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        KurtosisLabel              matlab.ui.control.Label
        EditField_5                matlab.ui.control.NumericEditField
        ResetButton                matlab.ui.control.Button
        DisplayButton              matlab.ui.control.Button
        PatientNameLabel           matlab.ui.control.Label
        AgeLabel                   matlab.ui.control.Label
        DateofBirthLabel           matlab.ui.control.Label
        BloodGroupLabel            matlab.ui.control.Label
        EditField_7                matlab.ui.control.EditField
        EditField_9                matlab.ui.control.EditField
        OriginalImageLabel         matlab.ui.control.Label
        ResizedgrayImageLabel      matlab.ui.control.Label
        SegmentedImLabel           matlab.ui.control.Label
        KMeansClusteredimageLabel  matlab.ui.control.Label
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        LoadButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_6                   matlab.ui.control.UIAxes
        EditField_10               matlab.ui.control.EditField
        EditField_11               matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EditField_1.Value = 0;
            app.EditField_2.Value = 0;
            app.EditField_3.Value = 0;
            app.EditField_4.Value = 0;
            app.EditField_5.Value = 0;
            %app.UIAxes.reset
            %app.UIAxes_3.reset
            %app.UIAxes_4.reset
            %app.UIAxes_5.reset
            
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            [sd1,cov,m,v,k,a,b,c,d] = cellsize_kmeans11();
            app.EditField_1.Value = m;
            app.EditField_2.Value = v;
            app.EditField_3.Value = cov;
            app.EditField_4.Value = sd1;
            app.EditField_5.Value = k;
            
            imshow(c,'parent' ,app.UIAxes_3,   'XData',[1 app.UIAxes_3.Position(3)],   'YData', [1 app.UIAxes_3.Position(4)])
            imshow(d,'parent' ,app.UIAxes_4, 'XData',[1 app.UIAxes_4.Position(3)], 'YData', [1 app.UIAxes_4.Position(4)])
            imshow(b,'parent' ,app.UIAxes_5, 'XData',[1 app.UIAxes_5.Position(3)], 'YData', [1 app.UIAxes_5.Position(4)])
            imshow(a,'parent' ,app.UIAxes_6, 'XData',[1 app.UIAxes_6.Position(3)], 'YData', [1 app.UIAxes_6.Position(4)])
           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            A = app.EditField_7.Value;
            AA = string(A);
            B = app.EditField_10.Value;
            BB = string(B);
            C = app.EditField_11.Value;
            CC= string(C);
            D = app.EditField_9.Value;
            DD = string(D);
            documents = tokenizedDocument([
              AA BB CC DD ]);
          
            file = uiputfile();
            
            writeTextDocument(documents,file,'Append',true)
            mydialog()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure('Visible', 'off');
            app.UI.Name = 'RBC';
            app.FileMenu = uimenu(app.UI);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UI);
            app.HelpMenu.Text = 'Help';
            app.ViewButton = uibutton(app.UI, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.MeanLabel = uilabel(app.UI);
            app.MeanLabel.Text = 'Mean';
            app.VarianceLabel = uilabel(app.UI);
            app.VarianceLabel.Text = 'Variance';
            app.CoVarianceLabel = uilabel(app.UI);
            app.CoVarianceLabel.Text = 'Co-Variance';
            app.StandardDeviationLabel = uilabel(app.UI);
            app.StandardDeviationLabel.Text = 'Standard Deviation';
            app.EditField_1 = uieditfield(app.UI, 'numeric');
            app.EditField_1.Editable = 'off';
            app.EditField_2 = uieditfield(app.UI, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.UI, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.UI, 'numeric');
            app.EditField_4.Editable = 'off';
            app.KurtosisLabel = uilabel(app.UI);
            app.KurtosisLabel.Text = 'Kurtosis';
            app.EditField_5 = uieditfield(app.UI, 'numeric');
            app.EditField_5.Editable = 'off';
            app.ResetButton = uibutton(app.UI, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DisplayButton = uibutton(app.UI, 'push');
            app.DisplayButton.Text = 'Display';
            app.PatientNameLabel = uilabel(app.UI);
            app.PatientNameLabel.Text = 'Patient Name';
            app.AgeLabel = uilabel(app.UI);
            app.AgeLabel.Text = {'Age'; ''};
            app.DateofBirthLabel = uilabel(app.UI);
            app.DateofBirthLabel.Text = {'Date of Birth'; ''};
            app.BloodGroupLabel = uilabel(app.UI);
            app.BloodGroupLabel.Text = 'Blood Group';
            app.EditField_7 = uieditfield(app.UI, 'text');
            app.EditField_9 = uieditfield(app.UI, 'text');
            app.OriginalImageLabel = uilabel(app.UI);
            app.OriginalImageLabel.Text = 'Original Image';
            app.ResizedgrayImageLabel = uilabel(app.UI);
            app.ResizedgrayImageLabel.Text = {'Resized gray Image'; ''};
            app.SegmentedImLabel = uilabel(app.UI);
            app.SegmentedImLabel.Text = 'Segmented Im';
            app.KMeansClusteredimageLabel = uilabel(app.UI);
            app.KMeansClusteredimageLabel.Text = 'K-Means Clustered image';
            app.UIAxes_3 = uiaxes(app.UI);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_4 = uiaxes(app.UI);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.LoadButton = uibutton(app.UI, 'push');
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.UI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save'; ''};
            app.UIAxes_5 = uiaxes(app.UI);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_6 = uiaxes(app.UI);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.EditField_10 = uieditfield(app.UI, 'text');
            app.EditField_11 = uieditfield(app.UI, 'text');
            % Show the figure after all components are created
            app.UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RBC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end